classdef UNIDSR3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        T1resolutionKnob       matlab.ui.control.Knob
        T1resolutionKnobLabel  matlab.ui.control.Label
        T1maxKnob              matlab.ui.control.Knob
        T1maxKnobLabel         matlab.ui.control.Label
        InvEffSpinner          matlab.ui.control.Spinner
        InvEffSpinnerLabel     matlab.ui.control.Label
        PFSpinner              matlab.ui.control.Spinner
        PFSpinnerLabel         matlab.ui.control.Label
        NslicesSpinner         matlab.ui.control.Spinner
        NslicesSpinnerLabel    matlab.ui.control.Label
        TI2sSpinner            matlab.ui.control.Spinner
        TI2sSpinnerLabel       matlab.ui.control.Label
        TI1sSpinner            matlab.ui.control.Spinner
        TI1sSpinnerLabel       matlab.ui.control.Label
        FA2degSpinner          matlab.ui.control.Spinner
        FA2degSpinnerLabel     matlab.ui.control.Label
        FA1degSpinner          matlab.ui.control.Spinner
        FA1degSpinnerLabel     matlab.ui.control.Label
        ESmsSpinner            matlab.ui.control.Spinner
        ESmsSpinnerLabel       matlab.ui.control.Label
        TRsSpinner             matlab.ui.control.Spinner
        TRsSpinnerLabel        matlab.ui.control.Label
        SNRtestsKnob           matlab.ui.control.DiscreteKnob
        SNRtestsKnobLabel      matlab.ui.control.Label
        SNRKnob                matlab.ui.control.Knob
        SNRKnobLabel           matlab.ui.control.Label
        B1testsKnob            matlab.ui.control.DiscreteKnob
        B1testsKnobLabel       matlab.ui.control.Label
        PresetsListBox         matlab.ui.control.ListBox
        PresetsListBoxLabel    matlab.ui.control.Label
        B1inhomogeneityKnob    matlab.ui.control.Knob
        B1inhomogeneityLabel   matlab.ui.control.Label
        B0DropDown             matlab.ui.control.DropDown
        B0DropDownLabel        matlab.ui.control.Label
        RightPanel             matlab.ui.container.Panel
        UIAxes4                matlab.ui.control.UIAxes
        UIAxes3                matlab.ui.control.UIAxes
        UIAxes2                matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    methods (Access = private)

        function [Inv1,Inv2,T_1_array]= Get_Inv1and2(app)
            % T_I_1 = app.TI1EditField.Value;
            % T_I_2 = app.TI2EditField.Value;
            % FA1  = app.FA1EditField.Value;
            % FA2  = app.FA2EditField.Value;
            % T_R_flash   = app.ESEditField.Value;
            % T_R_mp2rage    = app.TREditField.Value;
            % N_slices = app.NslicesEditField.Value;
            % PF = app.PFEditField.Value;
            % M_0 = app.M0EditField.Value;
            T_1 = linspace(0,app.T1maxKnob.Value,ceil(app.T1resolutionKnob.Value));
            T_I_1 = app.TI1sSpinner.Value;
            T_I_2 = app.TI2sSpinner.Value;
            FA1  = app.FA1degSpinner.Value;
            FA2  = app.FA2degSpinner.Value;
            T_R_flash   = app.ESmsSpinner.Value;
            T_R_mp2rage    = app.TRsSpinner.Value;
            N_slices = app.NslicesSpinner.Value;
            Eff_Inv = app.InvEffSpinner.Value;
            PF = app.PFSpinner.Value;
            temp = app.SNRtestsKnob.Value;
            if ischar(temp)
                if strcmp(temp,'Off')
                    N_tests = 1;
                else
                    if isnumeric(str2double(temp))
                        N_tests = str2double(temp)+1;
                    end

                end

            end

            M_0 = 1;
            B_1_fac = B1factors(app);

            T_R_flash = T_R_flash.*1e-3;
            N_B_1_fac = numel(B_1_fac);
            N_slices_pf        =N_slices*[PF-0.5 0.5];  % This is the actual number of echoes, used to calculate the echo train length
            N_T_1 = numel(T_1);
            alpha_1 = FA1.*B_1_fac(:);
            alpha_2 = FA2.*B_1_fac(:);
            alpha_1 = repmat(alpha_1,1,N_T_1);
            alpha_2 = repmat(alpha_2,1,N_T_1);


            N_echoes_bef_T_E = N_slices_pf(1);
            N_echoes_aft_T_E = N_slices_pf(2);
            N_echoes_tot = sum(N_slices_pf);

            % Signal = zeros(N_B_1_fac,N_T_1,N_tests,N_M_0,2);

            T_acq            = N_echoes_tot     * T_R_flash;
            T_acq_bef_T_E    = N_echoes_bef_T_E * T_R_flash;
            T_acq_aft_T_E    = N_echoes_aft_T_E * T_R_flash;

            T_A = T_I_1 - T_acq_bef_T_E;
            T_C = T_R_mp2rage - T_I_2 - T_acq_aft_T_E;
            T_B = T_R_mp2rage - 2*T_acq - T_A - T_C;

            E_R = exp(-T_R_flash./T_1);
            E_A = exp(-T_A./T_1);
            E_B = exp(-T_B./T_1);
            E_C = exp(-T_C./T_1);

            E_R = repmat(E_R,N_B_1_fac,1);
            E_A = repmat(E_A,N_B_1_fac,1);
            E_B = repmat(E_B,N_B_1_fac,1);
            E_C = repmat(E_C,N_B_1_fac,1);

            ca1 = cos(alpha_1*pi/180);
            ca2 = cos(alpha_2*pi/180);

            ca1E_R = ca1.*E_R;
            ca2E_R = ca2.*E_R;


            X = M_0.*(1-E_R).*(1-ca2E_R.^N_echoes_tot)./(1-ca2E_R).*E_C + M_0.*(1-E_C);
            Y = M_0.*(1-E_R).*(1-ca1E_R.^N_echoes_tot)./(1-ca1E_R).*E_B.*ca2E_R.^N_echoes_tot.*E_C + M_0.*(1-E_B).*ca2E_R.^N_echoes_tot.*E_C;
            Z = M_0.*(1-E_A).*ca1E_R.^N_echoes_tot.*E_B.*ca2E_R.^N_echoes_tot.*E_C;

            M_z_ss_denom = 1+Eff_Inv*ca1E_R.^N_echoes_tot.*ca2E_R.^N_echoes_tot.*E_A.*E_B.*E_C;
            M_z_ss_numer = X+Y+Z;
            M_z_ss = M_z_ss_numer./M_z_ss_denom;

            Mz1 = -Eff_Inv.*M_z_ss;
            Mz2 = Mz1.*E_A + M_0.*(1-E_A);
            Mz3 = Mz2.*ca1E_R.^N_echoes_bef_T_E + M_0.*(1-E_R).*(1-ca1E_R.^N_echoes_bef_T_E)./(1-ca1E_R);

            Mz4 = Mz3.*ca1E_R.^N_echoes_aft_T_E + M_0.*(1-E_R).*(1-ca1E_R.^N_echoes_aft_T_E)./(1-ca1E_R);
            Mz5 = Mz4.*E_B+M_0.*(1-E_B);
            Mz6 = Mz5.*ca2E_R.^N_echoes_bef_T_E + M_0.*(1-E_R).*(1-ca2E_R.^N_echoes_bef_T_E)./(1-ca2E_R);

            GRE1 = sin(alpha_1*pi/180).*Mz3;
            GRE2 = sin(alpha_2*pi/180).*Mz6;


            % Signal(:,:,1,1) = GRE1;
            % Signal(:,:,1,2) = GRE2;
            %
            % MaxSig = max(abs(Signal(:)));
            % Signal = Signal./MaxSig;

            Inv1 = repmat(GRE1,[1,1,N_tests]);
            Inv2 = repmat(GRE2,[1,1,N_tests]);

            % Noise1 = 1./SNR/2 .*randn(N_B_1_fac,N_T_1,N_tests);
            % Noise2 = 1./SNR/2 .*randn(N_B_1_fac,N_T_1,N_tests);
            % 
            % Noise1(:,:,1) = 0;
            % Noise2(:,:,1) = 0;
            % 
            % Signal1_ = Signal1+Noise1;
            % Signal2_ = Signal2+Noise2;
            % % max(Signal1(:))
            % 
            % Signal = cat(4,Signal1_,Signal2_);
            % 
            % 
            % 
            % 
            % % Intensity = squeeze(real(Signal(:,:,:,1).*conj(Signal(:,:,:,2))))./squeeze(abs(Signal(:,:,:,1)).^2+abs(Signal(:,:,:,2)).^2);
            % app.UNI = squeeze(real(Signal(:,:,:,1).*conj(Signal(:,:,:,2))))./squeeze(abs(Signal(:,:,:,1)).^2+abs(Signal(:,:,:,2)).^2);
            % app.Inv1 = squeeze(abs(Signal(:,:,:,1)));%./max(max(max(max(abs(squeeze(Signal(:,:,:,1)))))));
            % app.Inv2 = squeeze(abs(Signal(:,:,:,2)));%./max(max(max(max(abs(squeeze(Signal(:,:,:,2)))))));
            % % MIP = squeeze(min(abs(Signal(:,:,:,1)),abs(Signal(:,:,:,2)))./(abs(Signal(:,:,:,1))+abs(Signal(:,:,:,2))));
            % app.DSR = (abs(Signal(:,:,:,1))-abs(Signal(:,:,:,2)))./(2.*(abs(Signal(:,:,:,1))+abs(Signal(:,:,:,2))));


            T_1_array = repmat(T_1,[N_B_1_fac,1,N_tests]);


        end
       
        function Plot(app,Y,T_1,Ax)
            % global app
            % clear Ax
            B_1_fac = B1factors(app);
            temp = app.B0DropDown.Value;
            B_0 = str2double(temp);
            % Ax = app.UIAxes4;
            % Ax = app.axis_MainMP2RAGE;
            % hold(Ax, 'off')
            if B_0 > 2.5 && B_0 < 3.5
                T1WM=0.85;
                T1GM=1.35;
                T1CSF=2.8;
            elseif B_0 > 6.5 && B_0 < 7.5
                T1WM=1.1;
                T1GM=1.85;
                T1CSF=3.5;
            else
                disp('T1 values of GM, WM and CSF for this field strength not implemented')
                T1WM=-5;
                T1GM=-5;
                T1CSF=-5;
            end

            N_tests = size(Y,3);
            N_B_1_fac = numel(B_1_fac);


            % hold on
            if N_tests > 1
                for n = N_tests:-1:2
                    temp = squeeze(Y(:,:,n));
                    temp2 = squeeze(T_1(:,:,n));
                    for m = 1:N_B_1_fac
                        plot(temp(m,:).',temp2(m,:).','color',[0.5 0.5 0.5]*B_1_fac(m),'linewidth',1,'parent',Ax)
                        hold(Ax, 'on');
                    end
                end
            end
            temp = squeeze(Y(:,:,1));
            temp2 = squeeze(T_1(:,:,1));
            for m = 1:N_B_1_fac
                plot(temp(m,:).',temp2(m,:).','color',[0.5 0.5 0.5]*B_1_fac(m),'linewidth',3,'parent',Ax)
                hold(Ax, 'on');
            end

            % plot(temp.',temp2.','color',[0.5 0.5 0.5]*B_1_fac(m),'linewidth',3,'parent',app.axis_Main)
            % axis([-0.5 0.5 0 4])

            % hold on
            line([-0.5 0.5],[T1CSF T1CSF]','Linewidth',2,'color','blue','parent',Ax)
            % hold on
            line([-0.5 0.5],[T1GM T1GM]','Linewidth',2,'color',[0.2,0.2,0.2],'parent',Ax)
            % hold on
            line([-0.5 0.5],[T1WM T1WM]','Linewidth',2,'color',[0.7,0.7,0.7],'parent',Ax)

            % text(0.35,T1WM+0.1,'White Matter')
            % text(0.35,T1GM+0.1,'Grey Matter')
            % text(0.35,T1CSF+0.1,'CSF')

            % title([{['Slices: ' num2str(N_slices) ' PF:' num2str(PF) ' TI: ' num2str(T_I(1)*1000) 'ms/ ' num2str(T_I(2)*1000) 'ms']},...
            % {['TR (FLASH/MP2RAGE): ' num2str(T_R_flash*1000) 'ms/ ' num2str(T_R_mp2rage*1000) 'ms ']},...
            % {['Flipangles (1/2): ' num2str(alpha(1)),'^\circ/ ' num2str(alpha(2)),'^\circ']}])

            % title('UNI','FontSize',14,'parent',Ax)
            temp = app.T1maxKnob.Value;
            set(Ax,'YLim',[0,temp]);
            grid(Ax,'on')
            set(Ax,'XTick',[-.5 :0.25:.5]),

            set(Ax,'Fontsize',12);

            % Legend = cell(N_B_1_fac,1);
            % for n = 1:N_B_1_fac
            %    Legend{n} = sprintf('%0.2f',B_1_fac(n)) ;
            % end
            % Legend{N_B_1_fac+1} = 'Nominal';
            % legend(Legend)
            drawnow
        end

        function B1fac = B1factors(app)
            B1inhomogeneity = app.B1inhomogeneityKnob.Value;

            if B1inhomogeneity < 1
                B1fac = 1;
                return
            end

            temp= app.B1testsKnob.Value;
            if ischar(temp)
                if strcmp(temp,'Off')
                B1fac = 1;
                else
                    B1tests = str2double(temp);
                    B1fac = linspace(-B1inhomogeneity,B1inhomogeneity,B1tests)/100+1;
                end
            end
            % disp(B1testsKnob.Value)
            % app.B1fac = B1fac;
            
        end

        function DSR = Get_DSR(app,Inv1,Inv2)
            DSR = (abs(Inv1)-abs(Inv2))./(2.*abs(Inv1)+2.*abs(Inv2));
            
        end

        function UNI = Get_UNI(app,Inv1,Inv2)
            UNI = Inv1.*Inv2./(abs(Inv1).^2+abs(Inv2).^2);
            
        end

        function [Inv1n,Inv2n] = Add_Noise(app,Inv1,Inv2)
            % size(Inv1)
            temp = app.SNRtestsKnob.Value;
            temp2 = app.SNRKnob.Value;
            if ischar(temp)
                if strcmp(temp,'Off')
                    % SNR = 1e10;
                    Inv1n = Inv1;
                    Inv2n = Inv2;
                else
                    if isnumeric(str2double(temp))
                        SNR = temp2;
                        MaxSignal = max([Inv1 Inv2],[],'all');
                        Noise1 = MaxSignal./SNR .*randn(size(Inv1)); Noise1(:,:,1) = 0;
                        Noise2 = MaxSignal./SNR .*randn(size(Inv2)); Noise2(:,:,1) = 0;
                        Inv1n = Inv1 + Noise1;
                        Inv2n = Inv2 + Noise2;
                        % size(Noise1)
                    end

                end
                
            end
            %     SNR = app.SNRKnob.Value;

            % 
            % 
            % 
            % 
            % 
            % 
            % 
            % Struct.noisyINV_1 = Signal1 + Noise1;
            % Struct.noisyINV_2 = Signal2 + Noise2;

        end

        function results = func(app)
            disp('hello')
            
            [Inv1,Inv2,T_1_array]= Get_Inv1and2(app);
            [Inv1n,Inv2n]=Add_Noise(app,Inv1,Inv2);
            DSR = Get_DSR(app,Inv1n,Inv2n);
            UNI = Get_UNI(app,Inv1n,Inv2n);
            Ax = app.UIAxes3;cla(Ax)
            Plot(app,UNI,T_1_array,Ax);
            Ax = app.UIAxes4;cla(Ax)
            Plot(app,DSR,T_1_array,Ax);
            Ax = app.UIAxes;cla(Ax)
            Plot(app,Inv1n,T_1_array,Ax);
            Ax = app.UIAxes2;cla(Ax)
            Plot(app,Inv2n,T_1_array,Ax);
            results = 1;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: not associated with a component
        function ESEditFieldValueChanged(app, event)
            value = app.ESEditField.Value;
            results = func(app);
        end

        % Callback function: not associated with a component
        function TREditFieldValueChanged(app, event)
            value = app.TREditField.Value;
            results = func(app);
        end

        % Value changed function: PresetsListBox
        function PresetsListBoxValueChanged(app, event)
            value = app.PresetsListBox.Value;

switch value
    case 'LessBias'
        % LessBias: TR,MP2RAGE=5s, TR,FLASH=7.18ms, 1/2=3/5,
% TI,1/TI,2=500ms/1900ms.
            app.TI1sSpinner.Value = 0.5;
            app.TI2sSpinner.Value = 1.9;
            app.FA1degSpinner.Value = 3;
            app.FA2degSpinner.Value = 5;
            app.ESmsSpinner.Value = 7.18;
            app.TRsSpinner.Value = 5;
            pp.NslicesSpinner.Value = 192;
            app.InvEffSpinner.Value = 0.96;
            app.PFSpinner.Value = 0.75;
    case 'Standard'
            app.TI1sSpinner.Value = 0.7;
            app.TI2sSpinner.Value = 2.5;
            app.FA1degSpinner.Value = 4;
            app.FA2degSpinner.Value = 5;
            app.ESmsSpinner.Value = 7.18;
            app.TRsSpinner.Value = 5;
            pp.NslicesSpinner.Value = 192;
            app.InvEffSpinner.Value = 0.96;
            app.PFSpinner.Value = 0.75;

    otherwise

end


            results = func(app);
        end

        % Value changed function: B1testsKnob
        function B1testsKnobValueChanged(app, event)
            value = app.B1testsKnob.Value;

            if ischar(value)
                if strcmp(value,'Off')
                % set(app.B1inhomogeneityKnob.Value,0)
                app.B1inhomogeneityKnob.Value = 0;
                end
            end
            results = func(app);
        end

        % Value changed function: B1inhomogeneityKnob
        function B1inhomogeneityKnobValueChanged(app, event)
            value = app.B1inhomogeneityKnob.Value;
            if value < 1
                app.B1testsKnob.Value = 'Off';
            end
            results = func(app);
        end

        % Value changed function: SNRtestsKnob
        function SNRtestsKnobValueChanged(app, event)
            value = app.SNRtestsKnob.Value;
            results = func(app);
        end

        % Value changed function: SNRKnob
        function SNRKnobValueChanged(app, event)
            value = app.SNRKnob.Value;
            results = func(app);
        end

        % Callback function: not associated with a component
        function FA1EditFieldValueChanged(app, event)
            value = app.FA1EditField.Value;
            results = func(app);
        end

        % Callback function: not associated with a component
        function FA2EditFieldValueChanged(app, event)
            value = app.FA2EditField.Value;
            results = func(app);
        end

        % Callback function: not associated with a component
        function TI1EditFieldValueChanged(app, event)
            value = app.TI1EditField.Value;
            results = func(app);
        end

        % Callback function: not associated with a component
        function TI2EditFieldValueChanged(app, event)
            value = app.TI2EditField.Value;
            results = func(app);
        end

        % Callback function: not associated with a component
        function NslicesEditFieldValueChanged(app, event)
            value = app.NslicesEditField.Value;
            results = func(app);
        end

        % Value changed function: B0DropDown
        function B0DropDownValueChanged(app, event)
            value = app.B0DropDown.Value;
            results = func(app);
        end

        % Drop down opening function: B0DropDown
        function B0DropDownOpening(app, event)
            % results = func(app);
        end

        % Callback function: not associated with a component
        function InvEffEditFieldValueChanged(app, event)
            value = app.InvEffEditField.Value;
            results = func(app);
        end

        % Callback function: not associated with a component
        function M0ButtonGroupSelectionChanged(app, event)
            selectedButton = app.M0ButtonGroup.SelectedObject;
            results = func(app);
        end

        % Value changed function: TRsSpinner
        function TRsSpinnerValueChanged(app, event)
            value = app.TRsSpinner.Value;
            results = func(app);
        end

        % Value changed function: ESmsSpinner
        function ESmsSpinnerValueChanged(app, event)
            value = app.ESmsSpinner.Value;
            results = func(app);
        end

        % Value changed function: FA1degSpinner
        function FA1degSpinnerValueChanged(app, event)
            value = app.FA1degSpinner.Value;
            results = func(app);
        end

        % Value changed function: FA2degSpinner
        function FA2degSpinnerValueChanged(app, event)
            value = app.FA2degSpinner.Value;
            results = func(app);
        end

        % Value changed function: TI1sSpinner
        function TI1sSpinnerValueChanged(app, event)
            value = app.TI1sSpinner.Value;
            results = func(app);
        end

        % Value changed function: TI2sSpinner
        function TI2sSpinnerValueChanged(app, event)
            value = app.TI2sSpinner.Value;
            results = func(app);
        end

        % Value changed function: NslicesSpinner
        function NslicesSpinnerValueChanged(app, event)
            value = app.NslicesSpinner.Value;
            results = func(app);
        end

        % Value changed function: PFSpinner
        function PFSpinnerValueChanged(app, event)
            value = app.PFSpinner.Value;
            results = func(app);
        end

        % Callback function: not associated with a component
        function M0ButtonGroupButtonDown(app, event)
            disp('asd')
        end

        % Value changed function: InvEffSpinner
        function InvEffSpinnerValueChanged(app, event)
            value = app.InvEffSpinner.Value;
            results = func(app);
        end

        % Value changed function: T1maxKnob
        function T1maxKnobValueChanged(app, event)
            value = app.T1maxKnob.Value;
            results = func(app);
        end

        % Value changed function: T1resolutionKnob
        function T1resolutionKnobValueChanged(app, event)
            value = app.T1resolutionKnob.Value;
            results = func(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {962, 962};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {359, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.B0DropDownLabel = uilabel(app.LeftPanel);
            app.B0DropDownLabel.Text = 'B0';
            app.B0DropDown = uidropdown(app.LeftPanel);
            app.B0DropDown.Items = {'3', '7', '9.4', '11.7'};
            app.B0DropDown.DropDownOpeningFcn = createCallbackFcn(app, @B0DropDownOpening, true);
            app.B0DropDown.ValueChangedFcn = createCallbackFcn(app, @B0DropDownValueChanged, true);
            app.B0DropDown.Tooltip = {'Only changes placement of T1/R1 lines for GM/WM/CSF'};
            app.B0DropDown.Value = '3';
            app.B1inhomogeneityLabel = uilabel(app.LeftPanel);
            app.B1inhomogeneityLabel.Text = 'B1 inhomogeneity (%)';
            app.B1inhomogeneityKnob = uiknob(app.LeftPanel, 'continuous');
            app.B1inhomogeneityKnob.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.B1inhomogeneityKnob.ValueChangedFcn = createCallbackFcn(app, @B1inhomogeneityKnobValueChanged, true);
            app.B1inhomogeneityKnob.Value = 1;
            app.PresetsListBoxLabel = uilabel(app.LeftPanel);
            app.PresetsListBoxLabel.Text = 'Presets';
            app.PresetsListBox = uilistbox(app.LeftPanel);
            app.PresetsListBox.Items = {'Standard', 'LessBias'};
            app.PresetsListBox.ValueChangedFcn = createCallbackFcn(app, @PresetsListBoxValueChanged, true);
            app.PresetsListBox.Value = 'Standard';
            app.B1testsKnobLabel = uilabel(app.LeftPanel);
            app.B1testsKnobLabel.Text = 'B1 tests';
            app.B1testsKnob = uiknob(app.LeftPanel, 'discrete');
            app.B1testsKnob.Items = {'Off', '3', '5'};
            app.B1testsKnob.ValueChangedFcn = createCallbackFcn(app, @B1testsKnobValueChanged, true);
            app.SNRKnobLabel = uilabel(app.LeftPanel);
            app.SNRKnobLabel.Text = 'SNR';
            app.SNRKnob = uiknob(app.LeftPanel, 'continuous');
            app.SNRKnob.ValueChangedFcn = createCallbackFcn(app, @SNRKnobValueChanged, true);
            app.SNRKnob.Value = 10;
            app.SNRtestsKnobLabel = uilabel(app.LeftPanel);
            app.SNRtestsKnobLabel.Text = 'SNR tests';
            app.SNRtestsKnob = uiknob(app.LeftPanel, 'discrete');
            app.SNRtestsKnob.Items = {'Off', '1', '2', '4'};
            app.SNRtestsKnob.ValueChangedFcn = createCallbackFcn(app, @SNRtestsKnobValueChanged, true);
            app.SNRtestsKnob.Value = '1';
            app.TRsSpinnerLabel = uilabel(app.LeftPanel);
            app.TRsSpinnerLabel.Text = 'TR [s]';
            app.TRsSpinner = uispinner(app.LeftPanel);
            app.TRsSpinner.Step = 0.1;
            app.TRsSpinner.Limits = [2 20];
            app.TRsSpinner.ValueChangedFcn = createCallbackFcn(app, @TRsSpinnerValueChanged, true);
            app.TRsSpinner.Value = 6;
            app.ESmsSpinnerLabel = uilabel(app.LeftPanel);
            app.ESmsSpinnerLabel.Text = 'ES [ms]';
            app.ESmsSpinner = uispinner(app.LeftPanel);
            app.ESmsSpinner.Step = 0.01;
            app.ESmsSpinner.Limits = [0 200];
            app.ESmsSpinner.ValueChangedFcn = createCallbackFcn(app, @ESmsSpinnerValueChanged, true);
            app.ESmsSpinner.Value = 7.18;
            app.FA1degSpinnerLabel = uilabel(app.LeftPanel);
            app.FA1degSpinnerLabel.Text = 'FA1 [deg]';
            app.FA1degSpinner = uispinner(app.LeftPanel);
            app.FA1degSpinner.Step = 0.1;
            app.FA1degSpinner.Limits = [0 90];
            app.FA1degSpinner.ValueChangedFcn = createCallbackFcn(app, @FA1degSpinnerValueChanged, true);
            app.FA1degSpinner.Value = 4;
            app.FA2degSpinnerLabel = uilabel(app.LeftPanel);
            app.FA2degSpinnerLabel.Text = 'FA2 [deg]';
            app.FA2degSpinner = uispinner(app.LeftPanel);
            app.FA2degSpinner.Step = 0.1;
            app.FA2degSpinner.Limits = [0 90];
            app.FA2degSpinner.ValueChangedFcn = createCallbackFcn(app, @FA2degSpinnerValueChanged, true);
            app.FA2degSpinner.Value = 5;
            app.TI1sSpinnerLabel = uilabel(app.LeftPanel);
            app.TI1sSpinnerLabel.Text = 'TI1 [s]';
            app.TI1sSpinner = uispinner(app.LeftPanel);
            app.TI1sSpinner.Step = 0.01;
            app.TI1sSpinner.Limits = [0 100];
            app.TI1sSpinner.ValueChangedFcn = createCallbackFcn(app, @TI1sSpinnerValueChanged, true);
            app.TI1sSpinner.Value = 1;
            app.TI2sSpinnerLabel = uilabel(app.LeftPanel);
            app.TI2sSpinnerLabel.Text = 'TI2 [s]';
            app.TI2sSpinner = uispinner(app.LeftPanel);
            app.TI2sSpinner.Step = 0.01;
            app.TI2sSpinner.Limits = [0 100];
            app.TI2sSpinner.ValueChangedFcn = createCallbackFcn(app, @TI2sSpinnerValueChanged, true);
            app.TI2sSpinner.Value = 2;
            app.NslicesSpinnerLabel = uilabel(app.LeftPanel);
            app.NslicesSpinnerLabel.Text = 'Nslices';
            app.NslicesSpinner = uispinner(app.LeftPanel);
            app.NslicesSpinner.Limits = [32 1024];
            app.NslicesSpinner.ValueChangedFcn = createCallbackFcn(app, @NslicesSpinnerValueChanged, true);
            app.NslicesSpinner.Value = 192;
            app.PFSpinnerLabel = uilabel(app.LeftPanel);
            app.PFSpinnerLabel.Text = 'PF';
            app.PFSpinner = uispinner(app.LeftPanel);
            app.PFSpinner.Step = 0.01;
            app.PFSpinner.Limits = [0 1];
            app.PFSpinner.ValueChangedFcn = createCallbackFcn(app, @PFSpinnerValueChanged, true);
            app.PFSpinner.Value = 0.75;
            app.InvEffSpinnerLabel = uilabel(app.LeftPanel);
            app.InvEffSpinnerLabel.Text = 'InvEff';
            app.InvEffSpinner = uispinner(app.LeftPanel);
            app.InvEffSpinner.Step = 0.01;
            app.InvEffSpinner.Limits = [0 1];
            app.InvEffSpinner.ValueChangedFcn = createCallbackFcn(app, @InvEffSpinnerValueChanged, true);
            app.InvEffSpinner.Value = 0.96;
            app.T1maxKnobLabel = uilabel(app.LeftPanel);
            app.T1maxKnobLabel.Text = 'T1 max';
            app.T1maxKnob = uiknob(app.LeftPanel, 'continuous');
            app.T1maxKnob.Limits = [0 10];
            app.T1maxKnob.ValueChangedFcn = createCallbackFcn(app, @T1maxKnobValueChanged, true);
            app.T1maxKnob.Value = 5;
            app.T1resolutionKnobLabel = uilabel(app.LeftPanel);
            app.T1resolutionKnobLabel.Text = 'T1 resolution';
            app.T1resolutionKnob = uiknob(app.LeftPanel, 'continuous');
            app.T1resolutionKnob.Limits = [128 1024];
            app.T1resolutionKnob.MajorTicks = [128 256 384 512 640 768 896 1024];
            app.T1resolutionKnob.ValueChangedFcn = createCallbackFcn(app, @T1resolutionKnobValueChanged, true);
            app.T1resolutionKnob.Value = 128;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Inv1')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Inv2')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'UNI')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.RightPanel);
            title(app.UIAxes4, 'DSR')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UNIDSR3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end